classdef transform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        pb1                  matlab.ui.control.UIAxes
        pb2                  matlab.ui.control.UIAxes
        pb3                  matlab.ui.control.UIAxes
        OpenButton           matlab.ui.control.Button
        TransformasiPanel    matlab.ui.container.Panel
        DFTButton            matlab.ui.control.Button
        DCTButton            matlab.ui.control.Button
        FFTButton            matlab.ui.control.Button
        WaveletButton        matlab.ui.control.Button
        InversDFTButton      matlab.ui.control.Button
        InversDCTButton      matlab.ui.control.Button
        InversFFTButton      matlab.ui.control.Button
        InversWaveletButton  matlab.ui.control.Button
        ExitButton           matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            app.UIFigure.Resize = 'on';
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global img;
            [nama_file, nama_path] = uigetfile('*.png;*.jpg;*.bmp;*.gif','Select Image');
            if ~isequal (nama_file,0)
                img = imread(fullfile(nama_path,nama_file));
                imshow(img,'Parent',app.pb1); 
            else
                return;
            end
        end

        % Button pushed function: DFTButton
        function DFTButtonPushed(app, event)
            global img;
            gray = rgb2gray(img);
            dft = fft2(gray);
            ab = abs(dft);
            imshow(ab,'Parent',app.pb2);
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.pb1 = uiaxes(app.UIFigure);
            app.pb2 = uiaxes(app.UIFigure);
            app.pb3 = uiaxes(app.UIFigure);
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.TransformasiPanel = uipanel(app.UIFigure);
            app.TransformasiPanel.Title = 'Transformasi';
            app.DFTButton = uibutton(app.TransformasiPanel, 'push');
            app.DFTButton.ButtonPushedFcn = createCallbackFcn(app, @DFTButtonPushed, true);
            app.DFTButton.Text = 'DFT';
            app.DCTButton = uibutton(app.TransformasiPanel, 'push');
            app.DCTButton.Text = 'DCT';
            app.FFTButton = uibutton(app.TransformasiPanel, 'push');
            app.FFTButton.Text = 'FFT';
            app.WaveletButton = uibutton(app.TransformasiPanel, 'push');
            app.WaveletButton.Text = 'Wavelet';
            app.InversDFTButton = uibutton(app.TransformasiPanel, 'push');
            app.InversDFTButton.Text = 'Invers DFT';
            app.InversDCTButton = uibutton(app.TransformasiPanel, 'push');
            app.InversDCTButton.Text = 'Invers DCT';
            app.InversFFTButton = uibutton(app.TransformasiPanel, 'push');
            app.InversFFTButton.Text = 'Invers FFT';
            app.InversWaveletButton = uibutton(app.TransformasiPanel, 'push');
            app.InversWaveletButton.Text = 'Invers Wavelet';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transform

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end