classdef transforms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageTransformationUIFigure  matlab.ui.Figure
        pb1                          matlab.ui.control.UIAxes
        pb2                          matlab.ui.control.UIAxes
        pb3                          matlab.ui.control.UIAxes
        OpenButton                   matlab.ui.control.Button
        TransformasiPanel            matlab.ui.container.Panel
        DFTButton                    matlab.ui.control.Button
        DCTButton                    matlab.ui.control.Button
        FFTButton                    matlab.ui.control.Button
        WaveletButton                matlab.ui.control.Button
        InverseDFTButton             matlab.ui.control.Button
        InverseDCTButton             matlab.ui.control.Button
        InverseFFTButton             matlab.ui.control.Button
        InverseWaveletButton         matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global img;
            [nama_file, nama_path] = uigetfile('*.png;*.jpg;*.bmp;*.gif','Select Image');
            if ~isequal (nama_file,0)
                img = imread(fullfile(nama_path,nama_file));
                imshow(img,'Parent',app.pb1); 
            else
                return;
            end
        end

        % Button pushed function: DFTButton
        function DFTButtonPushed(app, event)
            global img;
            global dft
            gray = rgb2gray(img);
            imdft = fft2(gray);
            %ab = abs(imdft);
            dft = fftshift(imdft);
            imshow(log(abs(dft)),[],'Parent',app.pb2);
           
        end

        % Button pushed function: InverseDFTButton
        function InverseDFTButtonPushed(app, event)
            global dft; 
            
            F = ifftshift(dft); 
            f = ifft2(F);
            imshow(f,[],'Parent',app.pb3);
            
        end

        % Button pushed function: DCTButton
        function DCTButtonPushed(app, event)
            global img;
            global dct
            gray = rgb2gray(img);
            dct = dct2(gray);
            imshow(log(abs(dct)),[],'Parent',app.pb2);
        end

        % Button pushed function: InverseDCTButton
        function InverseDCTButtonPushed(app, event)
            global dct;
            invdct = idct2(dct);
            imshow(invdct,[],'Parent',app.pb3);
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            global img;
            global fft;
            gray = rgb2gray(img);
            imfft = fft2(gray);
            %ab = abs(imdft);
            fft = fftshift(imfft);
            imshow(log(1+abs(fft)),[],'Parent',app.pb2);
        end

        % Button pushed function: InverseFFTButton
        function InverseFFTButtonPushed(app, event)
            global fft; 
            
            F = ifftshift(fft); 
            f = ifft2(F);
            imshow(f,[],'Parent',app.pb3);
        end

        % Button pushed function: WaveletButton
        function WaveletButtonPushed(app, event)
            global img;
            global dwt;
            gray = rgb2gray(img);
            dwt = dwt2(gray,'haar');
            imshow(dwt,'Parent',app.pb2);
        end

        % Button pushed function: InverseWaveletButton
        function InverseWaveletButtonPushed(app, event)
            global dwt; 
            
            idwt = idwt2(dwt,'haar'); 
            imshow(idwt,[],'Parent',app.pb3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageTransformationUIFigure = uifigure;
            app.ImageTransformationUIFigure.Name = 'Image Transformation';
            app.pb1 = uiaxes(app.ImageTransformationUIFigure);
            app.pb2 = uiaxes(app.ImageTransformationUIFigure);
            app.pb3 = uiaxes(app.ImageTransformationUIFigure);
            app.OpenButton = uibutton(app.ImageTransformationUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.TransformasiPanel = uipanel(app.ImageTransformationUIFigure);
            app.TransformasiPanel.Title = 'Transformasi';
            app.DFTButton = uibutton(app.TransformasiPanel, 'push');
            app.DFTButton.ButtonPushedFcn = createCallbackFcn(app, @DFTButtonPushed, true);
            app.DFTButton.Text = 'DFT';
            app.DCTButton = uibutton(app.TransformasiPanel, 'push');
            app.DCTButton.ButtonPushedFcn = createCallbackFcn(app, @DCTButtonPushed, true);
            app.DCTButton.Text = 'DCT';
            app.FFTButton = uibutton(app.TransformasiPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.WaveletButton = uibutton(app.TransformasiPanel, 'push');
            app.WaveletButton.ButtonPushedFcn = createCallbackFcn(app, @WaveletButtonPushed, true);
            app.WaveletButton.Text = 'Wavelet';
            app.InverseDFTButton = uibutton(app.TransformasiPanel, 'push');
            app.InverseDFTButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDFTButtonPushed, true);
            app.InverseDFTButton.Text = 'Inverse DFT';
            app.InverseDCTButton = uibutton(app.TransformasiPanel, 'push');
            app.InverseDCTButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDCTButtonPushed, true);
            app.InverseDCTButton.Text = 'Inverse DCT';
            app.InverseFFTButton = uibutton(app.TransformasiPanel, 'push');
            app.InverseFFTButton.ButtonPushedFcn = createCallbackFcn(app, @InverseFFTButtonPushed, true);
            app.InverseFFTButton.Text = 'Inverse FFT';
            app.InverseWaveletButton = uibutton(app.TransformasiPanel, 'push');
            app.InverseWaveletButton.ButtonPushedFcn = createCallbackFcn(app, @InverseWaveletButtonPushed, true);
            app.InverseWaveletButton.Text = 'Inverse Wavelet';
            app.ExitButton = uibutton(app.ImageTransformationUIFigure, 'push');
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transforms

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageTransformationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageTransformationUIFigure)
        end
    end
end